classdef labTopicosAppV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        TrayectoriaLabel            matlab.ui.control.Label
        ControldeRobotSCARALabel    matlab.ui.control.Label
        TiempoTrEditField           matlab.ui.control.NumericEditField
        TiempoEditFieldLabel        matlab.ui.control.Label
        VelocidadmmsEditField       matlab.ui.control.NumericEditField
        VelocidadmmsEditFieldLabel  matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        sendTrayectoryButton        matlab.ui.control.Button
        stopMovementButton          matlab.ui.control.Button
        moveDownButton              matlab.ui.control.Button
        moveLeftButton              matlab.ui.control.Button
        moveRightButton             matlab.ui.control.Button
        moveUpButton                matlab.ui.control.Button
        YfinalTrEditField           matlab.ui.control.NumericEditField
        YfinalEditFieldLabel        matlab.ui.control.Label
        XfinalTrEditField           matlab.ui.control.NumericEditField
        XfinalEditFieldLabel        matlab.ui.control.Label
        TipoTrDropDown              matlab.ui.control.DropDown
        TipoDropDownLabel           matlab.ui.control.Label
        CenterPanel                 matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        TabGroup2                   matlab.ui.container.TabGroup
        PlanoCartesianoTab          matlab.ui.container.Tab
        GridLayout6                 matlab.ui.container.GridLayout
        UIAxesCartesian             matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        PosicionesTab               matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        UIAxesPosiciones            matlab.ui.control.UIAxes
        AngulosTab                  matlab.ui.container.Tab
        GridLayout7                 matlab.ui.container.GridLayout
        UIAxesAngulos               matlab.ui.control.UIAxes
        VelocidadesTab              matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        UIAxesVelocidades           matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        SetCerosButton              matlab.ui.control.Button
        YactualEditField            matlab.ui.control.NumericEditField
        YactualEditFieldLabel       matlab.ui.control.Label
        XactualEditField            matlab.ui.control.NumericEditField
        XactualEditFieldLabel       matlab.ui.control.Label
        ShowYCheckBox               matlab.ui.control.CheckBox
        ShowXCheckBox               matlab.ui.control.CheckBox
        TiempoplotEditField         matlab.ui.control.NumericEditField
        TiempoplotEditFieldLabel    matlab.ui.control.Label
        LabelCopyright              matlab.ui.control.Label
        ConexinSerialLabel          matlab.ui.control.Label
        ConectarButton              matlab.ui.control.Button
        BaudRateDropDown            matlab.ui.control.DropDown
        BaudRateDropDownLabel       matlab.ui.control.Label
        PuertoDropDown              matlab.ui.control.DropDown
        PuertoDropDownLabel         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        
        
        %control=[{q0};{q1};{q2};{s0}];
        
        
        controles;
        %
        s;          %serial
        
        % circshift(v,length(v)-1)
        % graficas
        sampleTime=0.01;
        totalSamples;
        dataTime;
        dataReference;
        dataOut;
        dataError;
        dataControl;
        dataVelocidad1;
        dataVelocidad2;

        dataPulses;
        %grafica
        
        %tiempos de muestres


        %grafica
        counter=0;
        
        %enviar Torques
        timerTorques = timer;
        Ka=1.4048;


        %trayectoria
        stepTrayectory=0.05; %0.1

        vectPos=zeros(2,5);
        vectTheta=zeros(2,5);
        vectVel=zeros(2,5);
        vectTime=1:0.1:2;
        totalSteps;

        %Movement
        Xactual=0.15;
        Yactual=0;
        
        dataPulsos;
        dataTheta;
        dataVelocidad;
        dataPos;

        %control de posicion
        tmControl=0.1;
        
        dataActTheta1=0;
        dataActTheta2=0;

        posMotor=zeros(1,2);
        velMotor=zeros(1,2);

        theta1Error1=0;
        theta1Error2=0;
        theta2Error1=0;
        theta2Error2=0;
        torque1_1=0;
        torque1_2=0;
        torque2_1=0;

    end
    
    methods (Access = private)
        
        function [] = setLenData(~)
%             totalTime = app.TiempoplotEditField.Value;
%             app.dataTime = 0:app.sampleTime*10:totalTime;
%             app.totalSamples=length(app.dataTime);
%             app.dataReference=zeros(1,app.totalSamples);
%             app.dataOut=zeros(1,app.totalSamples);
%             app.dataError=zeros(1,app.totalSamples);
%             app.dataControl=zeros(1,app.totalSamples);
%             app.dataTheta=zeros(1,app.totalSamples);
%             app.dataVelocidad1=zeros(1,app.totalSamples);
%             app.dataVelocidad2=zeros(1,app.totalSamples);
%             app.dataPulses=zeros(1,app.totalSamples);
%             disp("total datos a plotear: "+ app.totalSamples)
        end
       
        
        function [] = generateTrajectory(app)

            time=app.TiempoTrEditField.Value;
            lenVectors=time/app.stepTrayectory;
            vectPoss=zeros(2,lenVectors);
            vectVell=zeros(2,lenVectors);
            Xo=app.Xactual;
            Yo=app.Yactual;
            Xf=app.XfinalTrEditField.Value;
            Yf=app.YfinalTrEditField.Value;
    
            wpts=[Xo Xf; Yo Yf];
            tvec = 0:app.stepTrayectory:time;
            tvec(1) = [];
            item=str2double(app.TipoTrDropDown.Value);
            switch item
                case 1
                    velx=(Xf-Xo)/time;
                    vely=(Yf-Yo)/time;
                    vectPoss(1,:)=linspace(Xo,Xf,lenVectors);
                    vectPoss(2,:)=linspace(Yo,Yf,lenVectors);
                    vectVell(1,:)=repmat(velx,1,lenVectors);
                    vectVell(2,:)=repmat(vely,1,lenVectors); 
                    vectVell(:,1)=[0;0];
                    vectVell(:,end)=[0;0];
                
                case 2
                    [vectPoss, vectVell, ~, ~] = cubicpolytraj(wpts, [0 time], tvec);
    
                case 3
                    [vectPoss,vectVell,~,~,~] = trapveltraj(wpts,lenVectors);
                 
            end
            app.vectPos=vectPoss;
            app.vectVel=vectVell;
            app.totalSteps=lenVectors;
            app.vectTime=tvec;
            
            
            x=vectPoss(1,:);
            y=vectPoss(2,:);
            app.vectTheta=zeros(2,lenVectors);

            %cinematica inversa
%             app.vectTheta(2,:)= 180-acosd( (l2^2+l1^2-x.^2-y.^2)/(2*l1*l2) );
%             
%             app.vectTheta(1,:) = atan2d(y,x) -  ...
%                 asind( l2*  sind(180-app.vectTheta(2,:))  / sqrt(x.^2+y.^2));

            for i=1:lenVectors
                [app.vectTheta(2,i) , app.vectTheta(1,i)]=cinematicaInversaScara(app,x(i),y(i));
            end

            %[app.vectTheta(2,:) , app.vectTheta(1,:)]=cinematicaInversaScara(app,x,y);
            
            datosCeros=zeros(2,lenVectors);

            app.dataPulses=datosCeros;
            app.dataTheta=datosCeros;
            app.dataVelocidad=datosCeros;
            app.dataPos=datosCeros;
            app.counter=0;
            %%plotear calculos
            plot(app.UIAxesCartesian,x,y)
            plot(app.UIAxesPosiciones, ...                            
                            app.vectTime,x, ...
                            app.vectTime,y);
            
        end
        function [q1,q2] = cinematicaInversaScara(~,x,y)

            l2=0.075;         l1=0.075; 
            %codo=0;
            theta_2=180-acosd((l2^2+l1^2-x.^2-y.^2)/(2*l1*l2));
            theta_1=atan2d(y,x)-asind(l2*sind(180-theta_2)/sqrt(x.^2+y.^2));
            
%             if codo
%                 theta_1=2*atan2d(y,x)-theta_1;
%                 theta_2=-theta_2;
%             end
          
            %comprobar que los angulos no se salen de rango
            errorTheta_1=-85>theta_1 | theta_1>85 | imag(theta_1) ~= 0 ;
            errorTheta_2=-100>theta_2 | theta_2>100 | imag(theta_2) ~= 0;
        
            if ~(any(errorTheta_1)||any( errorTheta_2) )
                q1=theta_1;
                q2=theta_2;
            else
                warning("configuracion no realizable")
            end
        end        
        function [] = sendTrayectory(app)
            
            delete(app.timerTorques)

            app.timerTorques=timer;
            app.timerTorques.StartDelay = 3;
            app.timerTorques.TimerFcn = @app.timerTorquesFcn;
            app.timerTorques.Period=app.tmControl;
            app.timerTorques.TasksToExecute=app.totalSteps;
            app.timerTorques.ExecutionMode  = 'fixedRate'; 
            app.timerTorques.StopFcn= @app.stopTimerTorques;
            start(app.timerTorques)
        end

        function [] = timerTorquesFcn(app,obj,~)
            actualStep=obj.TasksExecuted;

            refTheta1=app.vectTheta(1,actualStep);
            refTheta2=app.vectTheta(2,actualStep);
            
            theta1=app.dataActTheta1;
            theta2=app.dataActTheta2;

            theta1Error=refTheta1-theta1;
            theta2Error=refTheta2-theta2;
            app.dataTheta(1,actualStep) = theta1;
            app.dataTheta(2,actualStep) = theta2;
%             xact=
%             yact=
            
%             torque1 = (app.torque1_1 - 17.162*theta1Error - ...
%                 29.484*app.theta1Error1 + 13.122*app.theta1Error2)*app.Ka;
%             
%             torque2 = (app.torque2_1 - 17.162*theta2Error - ...
%                 29.484*app.theta2Error1 + 13.122*app.theta2Error2)*app.Ka;
             %%torque1= app.torque1_1 + const*theta1Error - 0.8const*theta1Error ;

            %graficar valores
            
            
            plot(app.UIAxesAngulos, ...                            
                            app.vectTime,app.vectTheta(1,:), ...
                            app.vectTime,app.vectTheta(2,:), ...
                            app.vectTime,app.dataTheta(1,:), ...
                            app.vectTime,app.dataTheta(2,:));

            %enviar Referencia  micro

            msg="R"+refTheta1+","+refTheta2;
            writeline(app.s,msg);


            %Actualizar Posiciones actuales
            updatePosActual(app,actualStep);
         
            if actualStep==app.totalSteps
                msg="P";
                writeline(app.s,msg);
            end

        end

        function [] = stopTimerTorques(app,~,~)
            msg="R"+0+","+0;
            writeline(app.s,msg);
            msg="P";
            writeline(app.s,msg);
            
        end

        function [] =updatePosActual(app,actualStep)
            %Actualizar Posiciones actuales
            app.Xactual=app.vectPos(1,actualStep);
            app.Yactual=app.vectPos(2,actualStep);
            app.XactualEditField.Value=app.Xactual;
            app.YactualEditField.Value=app.Yactual;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {243, '1x', 231};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Callback function: not associated with a component
        function EnviarButtonPushed(app, event)
            enviarTorques(app);
        end

        % Callback function
        function CambiarcontrolButtonPushed(app, event)
            indexControl=app.ControlDropDown.Value;
            coefs=app.controles{indexControl};
            msg="C"+coefs(1)+","+coefs(2)+","+coefs(3)+","+coefs(4);
            disp(msg)
            writeline(app.s,msg);

        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            
            if app.ConectarButton.Text=="Conectar"
                
                com=app.PuertoDropDown.Value;
                baudRate=str2double(app.BaudRateDropDown.Value);
                app.s = serialport(com,baudRate);
                app.ConectarButton.Text="Desconectar";
                configureCallback(app.s,"terminator",@readSerialData)
                setLenData(app);
            
            elseif app.ConectarButton.Text=="Desconectar"
                app.s.delete;
                app.ConectarButton.Text="Conectar";
            end
            
            function readSerialData(src,~)
                
                %{pulses1},{pulses2}
                data = readline(src);
                data = split(data,",");
                flag=data(1);
                data = str2double(data(2:3));
                if(any(~isnan(data)) && flag=="V")
                    len = app.totalSteps;
                    
                    
                    app.dataActTheta1=data(1);
                    app.dataActTheta2=data(2);

                    %posicion
                    app.dataVelocidad = circshift(app.dataVelocidad,len-1,2);
                    
                    app.dataPos = circshift(app.dataPos,len-1,2);
%                     
%                     theta1act=data(1);
%                     theta2act=data(2)
                    theta1act=data(1)*360/(11*36) + app.dataTheta(1,end-1);
                    theta2act=data(2)*360/(11*78) + app.dataTheta(2,end-1);
                    if(theta1act>1e3)
                        theta1act=app.dataTheta(1,end-1);
                    end
                    if(theta2act>1e3)
                        theta2act=app.dataTheta(2,end-1);
                    end
                    app.dataTheta(1,end) = theta1act;
                    app.dataTheta(2,end) = theta2act;
    
%                     app.dataPos(1,end)= ...
%                         0.075*cosd( app.dataTheta(1,end) ...
%                         + app.dataTheta(2,end) )...
%                         + 0.075*cosd(pp.dataTheta(1,end));
%     
%                     app.dataPos(2,end)= ...
%                         0.075*sind( app.dataTheta(1,end)        ...
%                         + app.dataTheta(2,end) )                ...
%                             + 0.075*sind(pp.dataTheta(1,end)) ;
                    
                    app.dataVelocidad(1,end) = 60*data(1)/(11*5e-3*78);
                    app.dataVelocidad(2,end) = 60*data(2)/(11*5e-3*378);
                    app.counter=app.counter+1;
                    
                    if(mod(app.counter,5)==0)

                        plot(app.UIAxesCartesian, ...                            
                            app.vectTime,app.vectTheta(1,:), ...
                            app.vectTime,app.vectTheta(2,:), ...
                            app.vectTime,app.dataTheta(1,:), ...
                            app.vectTime,app.dataTheta(2,:));
%                         plot(app.UIAxesPosiciones, ...
%                             app.vectTime,app.dataPos(1,:),  ...
%                             app.vectTime,app.dataPos(2,:),  ...
%                             app.vectTime,app.vectPos(1,:), ...
%                             app.vectTime,app.vectPos(2,:));
    %                     %plot(app.UIAxesPosiciones,app.dataTime,app.dataReference);
    %                     
    %                     plot(app.UIAxesVelocidades,app.dataTime,app.dataError);
                        
    %                     
    %                     plot(app.UIAxesControl,app.dataTime,app.dataControl);
    %                     plot(app.UIAxesVelocidad,app.dataTime,app.dataVelocidad1);
                    
                    end

                end
                

            end
            
        end

        % Drop down opening function: PuertoDropDown
        function PuertoDropDownOpening(app, event)
            puertos = split(serialportlist," ");
            app.PuertoDropDown.Items = puertos;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.timerTorques)
            delete(app.timerTorques)
            delete(app)
            
        end

        % Value changed function: TiempoplotEditField
        function TiempoplotEditFieldValueChanged(app, event)
            setLenData(app);
        end

        % Button pushed function: sendTrayectoryButton
        function sendTrayectoryButtonPushed(app, event)
            generateTrajectory(app);
            sendTrayectory(app);
        end

        % Button pushed function: SetCerosButton
        function SetCerosButtonPushed(app, event)
            app.Xactual=0.15;
            app.Yactual=0;
            app.XactualEditField.Value=app.Xactual;
            app.YactualEditField.Value=app.Yactual;

            datosCeros=zeros(2,app.totalSteps);

            app.dataPulses=datosCeros;
            app.dataTheta=datosCeros;
            app.dataVelocidad=datosCeros;
            app.dataPos=datosCeros;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TipoDropDownLabel = uilabel(app.LeftPanel);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.TipoTrDropDown = uidropdown(app.LeftPanel);
            app.TipoTrDropDown.Items = {'Lineal', 'Polinomial - 3', 'Trapezoidal'};
            app.TipoTrDropDown.ItemsData = {'1', '2', '3'};
            app.TipoTrDropDown.Value = '1';
            app.XfinalEditFieldLabel = uilabel(app.LeftPanel);
            app.XfinalEditFieldLabel.Text = 'X final';
            app.XfinalTrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.XfinalTrEditField.Value = 0.14;
            app.YfinalEditFieldLabel = uilabel(app.LeftPanel);
            app.YfinalEditFieldLabel.Text = 'Y final';
            app.YfinalTrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Panel = uipanel(app.LeftPanel);
            app.moveUpButton = uibutton(app.Panel, 'push');
            app.moveUpButton.Icon = fullfile(pathToMLAPP, 'flechaArriba.png');
            app.moveUpButton.Text = '';
            app.moveRightButton = uibutton(app.Panel, 'push');
            app.moveRightButton.Icon = fullfile(pathToMLAPP, 'flechaDerecha.png');
            app.moveRightButton.Text = '';
            app.moveLeftButton = uibutton(app.Panel, 'push');
            app.moveLeftButton.Icon = fullfile(pathToMLAPP, 'flechaIzquierda.png');
            app.moveLeftButton.Text = '';
            app.moveDownButton = uibutton(app.Panel, 'push');
            app.moveDownButton.Icon = fullfile(pathToMLAPP, 'flechaAbajo.png');
            app.moveDownButton.Text = '';
            app.stopMovementButton = uibutton(app.Panel, 'push');
            app.stopMovementButton.Icon = fullfile(pathToMLAPP, 'pausa.png');
            app.stopMovementButton.Text = '';
            app.sendTrayectoryButton = uibutton(app.Panel, 'push');
            app.sendTrayectoryButton.ButtonPushedFcn = createCallbackFcn(app, @sendTrayectoryButtonPushed, true);
            app.sendTrayectoryButton.Icon = fullfile(pathToMLAPP, 'accept.png');
            app.sendTrayectoryButton.Text = '';
            app.VelocidadmmsEditFieldLabel = uilabel(app.LeftPanel);
            app.VelocidadmmsEditFieldLabel.Text = 'Velocidad [mm/s]';
            app.VelocidadmmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TiempoEditFieldLabel = uilabel(app.LeftPanel);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.TiempoTrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TiempoTrEditField.ValueDisplayFormat = '%.1f';
            app.TiempoTrEditField.Value = 5;
            app.ControldeRobotSCARALabel = uilabel(app.LeftPanel);
            app.ControldeRobotSCARALabel.Text = 'Control de Robot SCARA';
            app.TrayectoriaLabel = uilabel(app.LeftPanel);
            app.TrayectoriaLabel.Text = 'Trayectoria';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.PosicionesTab = uitab(app.TabGroup);
            app.PosicionesTab.Title = 'Posiciones';
            app.GridLayout3 = uigridlayout(app.PosicionesTab);
            app.UIAxesPosiciones = uiaxes(app.GridLayout3);
            xlabel(app.UIAxesPosiciones, 'Time [s]')
            ylabel(app.UIAxesPosiciones, 'Pos [mm]')
            zlabel(app.UIAxesPosiciones, 'Z')
            app.AngulosTab = uitab(app.TabGroup);
            app.AngulosTab.Title = 'Angulos';
            app.GridLayout7 = uigridlayout(app.AngulosTab);
            app.UIAxesAngulos = uiaxes(app.GridLayout7);
            xlabel(app.UIAxesAngulos, 'Time [s]')
            ylabel(app.UIAxesAngulos, 'Angulo [°]')
            zlabel(app.UIAxesAngulos, 'Z')
            app.VelocidadesTab = uitab(app.TabGroup);
            app.VelocidadesTab.Title = 'Velocidades';
            app.GridLayout4 = uigridlayout(app.VelocidadesTab);
            app.UIAxesVelocidades = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesVelocidades, 'time [s]')
            ylabel(app.UIAxesVelocidades, 'Vel [mm/s]')
            zlabel(app.UIAxesVelocidades, 'Z')
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.PlanoCartesianoTab = uitab(app.TabGroup2);
            app.PlanoCartesianoTab.Title = 'Plano Cartesiano';
            app.GridLayout6 = uigridlayout(app.PlanoCartesianoTab);
            app.UIAxesCartesian = uiaxes(app.GridLayout6);
            xlabel(app.UIAxesCartesian, 'X [mm]')
            ylabel(app.UIAxesCartesian, 'Y [mm]')
            zlabel(app.UIAxesCartesian, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.PuertoDropDownLabel = uilabel(app.RightPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.PuertoDropDown = uidropdown(app.RightPanel);
            app.PuertoDropDown.Items = {};
            app.PuertoDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PuertoDropDownOpening, true);
            app.PuertoDropDown.Value = {};
            app.BaudRateDropDownLabel = uilabel(app.RightPanel);
            app.BaudRateDropDownLabel.Text = 'BaudRate';
            app.BaudRateDropDown = uidropdown(app.RightPanel);
            app.BaudRateDropDown.Items = {'9600', '115200', '230400'};
            app.BaudRateDropDown.Value = '230400';
            app.ConectarButton = uibutton(app.RightPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.ConexinSerialLabel = uilabel(app.RightPanel);
            app.ConexinSerialLabel.Text = 'Conexión Serial';
            app.LabelCopyright = uilabel(app.RightPanel);
            app.LabelCopyright.Text = {'El uso no autorizado así como la reproducción'; 'total o parcial de este contenido por cualquier '; 'persona o entidad, estará en contra de los '; 'derechos de autor. ©'};
            app.TiempoplotEditFieldLabel = uilabel(app.RightPanel);
            app.TiempoplotEditFieldLabel.Text = 'Tiempo plot';
            app.TiempoplotEditField = uieditfield(app.RightPanel, 'numeric');
            app.TiempoplotEditField.ValueChangedFcn = createCallbackFcn(app, @TiempoplotEditFieldValueChanged, true);
            app.TiempoplotEditField.Value = 10;
            app.ShowXCheckBox = uicheckbox(app.RightPanel);
            app.ShowXCheckBox.Text = 'Show X';
            app.ShowYCheckBox = uicheckbox(app.RightPanel);
            app.ShowYCheckBox.Text = 'Show Y';
            app.XactualEditFieldLabel = uilabel(app.RightPanel);
            app.XactualEditFieldLabel.Text = 'X actual';
            app.XactualEditField = uieditfield(app.RightPanel, 'numeric');
            app.XactualEditField.ValueDisplayFormat = '%.4f';
            app.XactualEditField.Editable = 'off';
            app.XactualEditField.Value = 0.15;
            app.YactualEditFieldLabel = uilabel(app.RightPanel);
            app.YactualEditFieldLabel.Text = 'Y actual';
            app.YactualEditField = uieditfield(app.RightPanel, 'numeric');
            app.YactualEditField.Editable = 'off';
            app.SetCerosButton = uibutton(app.RightPanel, 'push');
            app.SetCerosButton.ButtonPushedFcn = createCallbackFcn(app, @SetCerosButtonPushed, true);
            app.SetCerosButton.Text = 'Set Ceros';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = labTopicosAppV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end